classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EditField          matlab.ui.control.EditField
        ChooseImageButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [filename, path] = uigetfile('*.png');
if isequal(filename,0) || isequal(path,0)
    disp('Image selection canceled');
else
    disp(['Selected image: ' fullfile(path, filename)]);
    % Load the selected image
    I = imread(fullfile(path, filename));
    % Apply malaria cell prediction
    th = 50;
    radius = 2;
    grayimg = rgb2gray(I);
    sites_img = edge(grayimg, 'sobel', 0.05) - edge(grayimg, 'sobel');
    sites_img = imdilate(sites_img, strel('disk', radius));
    region_stats = regionprops(sites_img, grayimg, 'Area', 'BoundingBox');
    malaria = ~isempty(region_stats) && sum([region_stats.Area]) >= th;
    % Display the image and prediction result
    imshow(I, 'Parent', app.UIAxes);
    if malaria
        app.EditField.Value = 'Malaria detected';
    else
        app.EditField.Value  = 'Malaria not detected';
    end
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'INPUT IMAGE')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end